classdef appp_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ThereholdforBgEditField       matlab.ui.control.EditField
        ThereholdforBgEditFieldLabel  matlab.ui.control.Label
        NEditField                    matlab.ui.control.EditField
        NEditFieldLabel               matlab.ui.control.Label
        Threshold2EditField           matlab.ui.control.EditField
        Threshold2EditFieldLabel      matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.EditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        STDEditField                  matlab.ui.control.NumericEditField
        STDEditFieldLabel             matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        ADDColorButton                matlab.ui.control.Button
        finalimagelabel               matlab.ui.control.Label
        ColorMapsDropDown             matlab.ui.control.DropDown
        ColorMapsDropDownLabel        matlab.ui.control.Label
        IncludeDiagonalsCheckBox      matlab.ui.control.CheckBox
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        outputlabel                   matlab.ui.control.Label
        intensitylabel                matlab.ui.control.Label
        saturationlabel               matlab.ui.control.Label
        huelabel                      matlab.ui.control.Label
        TerminateButton               matlab.ui.control.Button
        ProcessButton                 matlab.ui.control.Button
        BinarizationDropDown          matlab.ui.control.DropDown
        BinarizationDropDownLabel     matlab.ui.control.Label
        EdgeDetectionDropDown         matlab.ui.control.DropDown
        EdgeDetectionDropDownLabel    matlab.ui.control.Label
        ImportButton                  matlab.ui.control.Button
        outputimage1                  matlab.ui.control.UIAxes
        finalimage                    matlab.ui.control.UIAxes
        Hueimage1                     matlab.ui.control.UIAxes
        intensityimage1               matlab.ui.control.UIAxes
        saturationimage1              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        MidOutput % Description
      
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screen_size = get(0, 'ScreenSize'); 
            app_width = 960;
            app_height = 540;
            app.UIFigure.Position = [(screen_size(3)-app_width)/2, (screen_size(4)-app_height)/2, app_width, app_height];

            set(app.ADDColorButton,'Visible','off');
            set(app.ProcessButton,'Visible','off');
            set(app.outputimage1,'Visible','off');
            set(app.outputlabel,'Visible','off');
            set(app.finalimage,'Visible','off');
            set(app.finalimagelabel,'Visible','off');
            set(app.NEditField,'Visible','off');
            set(app.NEditFieldLabel,'Visible','off');
            set(app.STDEditField,'Visible','off');
            set(app.ColorMapsDropDown,'Visible','off');
            set(app.STDEditFieldLabel,'Visible','off');
            set(app.ColorMapsDropDownLabel,'Visible','off');   
            set(app.ThresholdEditField,'Visible','off');
            set(app.Threshold2EditField,'Visible','off');
            set(app.EdgeDetectionDropDown,'Visible','off');
            set(app.EdgeDetectionDropDownLabel,'Visible','off');
            set(app.ThresholdEditFieldLabel,'Visible','off');
            set(app.Threshold2EditFieldLabel,'Visible','off');
            set(app.BinarizationDropDown,'Visible','off');
            set(app.BinarizationDropDownLabel,'Visible','off');
            set(app.IncludeDiagonalsCheckBox,'Visible','off');
            set(app.SaveButton,'Visible','off');
            set(app.ThereholdforBgEditField,'Visible','off');
            set(app.ThereholdforBgEditFieldLabel,'Visible','off');


        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)

            closereq();

        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            set(app.ADDColorButton,'Visible','off');
            set(app.ProcessButton,'Visible','off');
            set(app.outputimage1,'Visible','off');
            set(app.outputlabel,'Visible','off');
            set(app.finalimage,'Visible','off');
            set(app.finalimagelabel,'Visible','off');
            set(app.NEditField,'Visible','off');
            set(app.NEditFieldLabel,'Visible','off');
            set(app.STDEditField,'Visible','off');
            set(app.ColorMapsDropDown,'Visible','off');
            set(app.STDEditFieldLabel,'Visible','off');
            set(app.ColorMapsDropDownLabel,'Visible','off');   
            set(app.ThresholdEditField,'Visible','off');
            set(app.Threshold2EditField,'Visible','off');
            set(app.EdgeDetectionDropDown,'Visible','off');
            set(app.EdgeDetectionDropDownLabel,'Visible','off');
            set(app.ThresholdEditFieldLabel,'Visible','off');
            set(app.Threshold2EditFieldLabel,'Visible','off');
            set(app.BinarizationDropDown,'Visible','off');
            set(app.BinarizationDropDownLabel,'Visible','off');
            set(app.IncludeDiagonalsCheckBox,'Visible','off');
            set(app.SaveButton,'Visible','off');
            set(app.ThereholdforBgEditField,'Visible','off');
            set(app.ThereholdforBgEditFieldLabel,'Visible','off');


             [file,path] = uigetfile({'.jpg; *.JPG; *.jpeg; *.JPEG; *.img; *.IMG; *.tif; *.TIF; *.tiff, *.TIFF; *.png; *.pgm','Supported Files (.jpg,.img,.tiff,)'; ...
    '.jpg','jpg Files (.jpg)';...
    '.JPG','JPG Files (.JPG)';...
    '.jpeg','jpeg Files (.jpeg)';...
    '.JPEG','JPEG Files (.JPEG)';...
    '.img','img Files (.img)';...
    '.IMG','IMG Files (.IMG)';...
    '.tif','tif Files (.tif)';...
    '.TIF','TIF Files (.TIF)';...
    '.tiff','tiff Files (.tiff)';...
    '.TIFF','TIFF Files (.TIFF)'},...    
    'MultiSelect', 'on');
      

             app.FileNameEditField.Value = file;
             input = imread(file);
             [rows, cols, channel] = size(input);

            if(channel==1)
                input=cat(3,input,input,input);
            end
            
            [H,S,I]=rgbtohsi(input);
            H=cat(3,H,H,H);
            S=cat(3,S,S,S);
            I=cat(3,I,I,I);
            % app.Hueimage.ImageSource=H;
            imshow(H,'Parent',app.Hueimage1)
            % app.saturationimage.ImageSource=S;
            imshow(S,'Parent',app.saturationimage1)
            % app.intensityimage.ImageSource=I;
            imshow(I,'Parent',app.intensityimage1)
            set(app.EdgeDetectionDropDown,'Visible','on');
            set(app.EdgeDetectionDropDownLabel,'Visible','on');
            set(app.BinarizationDropDown,'Visible','on');
            set(app.BinarizationDropDownLabel,'Visible','on');
            set(app.IncludeDiagonalsCheckBox,'Visible','on'); 
            set(app.ProcessButton,'Visible','on');
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
                file= app.FileNameEditField.Value ;
                input = imread(file);
                [H,S,I]=rgbtohsi(input);
                ED = app.EdgeDetectionDropDown.Value;
                B = app.BinarizationDropDown.Value;
                value = get(app.IncludeDiagonalsCheckBox, 'Value');
                if value==1
                    diagonal=true;
                else
                    diagonal=false;
                end
        
           
            switch ED
               case 'Prewitt'
                    image = myedge(I,'prewitt',diagonal);
                    switch B

                        case 'Basic Global Thresholding'
                           t = app.ThereholdforBgEditField.Value;
                           t=str2double(t);
                           image=basicg(image,t);
                           app.MidOutput = image;
                        case 'Optimal Global Threshold'
                             image=otsu(image);
                             app.MidOutput = image;
                    end 
                

                case 'Sobel'
                        
                    image = myedge(I,'sobel',diagonal);
                    switch B
                         
                        case 'Basic Global Thresholding'
                            t = app.ThereholdforBgEditField.Value;
                             t=str2double(t);
                           image=basicg(image,t);
                           app.MidOutput = image;
                           
                        case 'Optimal Global Threshold'
                          image=otsu(image);
                          app.MidOutput = image;
                    end
                case 'Scharr'
               
                    image = myedge(I,'scharr',diagonal);
                   
                    switch B
                        case 'Basic Global Thresholding'
                            t = app.ThereholdforBgEditField.Value;
                             t=str2double(t);
                           image=basicg(image,t);
                           app.MidOutput = image;
                        case 'Optimal Global Threshold'
                          image=otsu(image);
                          app.MidOutput = image;
                    end
                case 'Kirsch'
                    image = myedge(I,'kirsch',diagonal);
  
                    switch B
                        case 'Basic Global Thresholding'
                             t = app.ThereholdforBgEditField.Value;
                             t=str2double(t);
                           image=basicg(image,t);
                           app.MidOutput = image;
                        case 'Optimal Global Threshold'
                          image=otsu(image);
                          app.MidOutput = image;
                    end

                case 'Marr Hildreth'
                    std=app.STDEditField.Value;
                    n=app.NEditField.Value;
                    Thrs=app.ThresholdEditField.Value;
                    image = applyMarrHildreth(I,std,n,Thrs);
             
                    switch B
                        case 'Basic Global Thresholding'
                        
                            t = app.ThereholdforBgEditField.Value;
                            t=str2double(t);
                           image=basicg(image,t);
                           app.MidOutput = image;
                        case 'Optimal Global Threshold'
                          image=otsu(image);
                          app.MidOutput = image;
                    end


                    case 'Canny'
                    lowThreshold = app.ThresholdEditField.Value;
                    lowThreshold=str2double(lowThreshold);
                    highThreshold=app.Threshold2EditField.Value;
                    highThreshold=str2double(highThreshold);
                    std=app.STDEditField.Value;
                    n=app.NEditField.Value;
                    n=str2double(n);

                    image = mycanny(I, n, std, lowThreshold, highThreshold);

                    switch B
                        case 'Basic Global Thresholding'
                            t = app.ThereholdforBgEditField.Value;
                            t=str2double(t);
                           image=basicg(image,t);
                           app.MidOutput = image;
                        case 'Optimal Global Threshold'
                          image=otsu(image);
                          app.MidOutput = image;

                    end
            end 
            image=mynormalize(image);
            image=cat(3,image,image,image);
            % app.outputimage1.ImageSource=image;
            imshow(image,'Parent',app.outputimage1)
            set(app.Hueimage1,'Visible','off');
            set(app.huelabel,'Visible','off');
            set(app.saturationimage1,'Visible','off');
            set(app.saturationlabel,'Visible','off');
            set(app.outputimage1,'Visible','on');
            set(app.outputlabel,'Visible','on');
            set(app.ColorMapsDropDown,'Visible','on');
            set(app.ColorMapsDropDownLabel,'Visible','on');   
            set(app.ADDColorButton,'Visible','on');
           
        end

        % Value changed function: IncludeDiagonalsCheckBox
        function IncludeDiagonalsCheckBoxValueChanged(app, event)
            value = app.IncludeDiagonalsCheckBox.Value;
             switch value
                case 'Marr Hildreth'
                    
                 case 'Kirsch'

            end
            
        end

        % Value changed function: ColorMapsDropDown
        function ColorMapsDropDownValueChanged(app, event)
            value = app.ColorMapsDropDown.Value;
            switch value
                case 'None'
                    app.ADDColorButton.Visible = 'off';
                otherwise
                    app.ADDColorButton.Visible ='on';
            end
        end

        % Button pushed function: ADDColorButton
        function ADDColorButtonPushed(app, event)
            set(app.finalimage,'Visible','on');
            set(app.finalimagelabel,'Visible','on');
            value = app.ColorMapsDropDown.Value;
            value1= mycolor(app.MidOutput, value);
            % app.finalimage.ImageSource=value1;   
            imshow(value1, 'Parent', app.finalimage)
            set(app.SaveButton,'Visible','on');
            

        end

        % Value changed function: EdgeDetectionDropDown
        function EdgeDetectionDropDownValueChanged(app, event)
            value=app.EdgeDetectionDropDown.Value;
            switch value
                case 'Marr Hildreth'
                    set(app.NEditField,'Visible','on');
                    set(app.NEditFieldLabel,'Visible','on');
                    set(app.STDEditField,'Visible','on');
                    set(app.STDEditFieldLabel,'Visible','on');
                    set(app.ThresholdEditField,'Visible','on');
                    set(app.ThresholdEditFieldLabel,'Visible','on');
                 case 'Kirsch'
                     set(app.IncludeDiagonalsCheckBox,'Visible','off');
            
                case 'Canny'

                    set(app.Threshold2EditFieldLabel,'Visible','on');
                    set(app.ThresholdEditField,'Visible','on');
                    set(app.ThresholdEditFieldLabel,'Visible','on');
                    set(app.Threshold2EditField,'Visible','on');
                    set(app.NEditField,'Visible','off');
                    set(app.NEditFieldLabel,'Visible','on');
                    set(app.STDEditField,'Visible','on');
                    set(app.NEditField,'Visible','on');
                    set(app.NEditFieldLabel,'Visible','on');
                    set(app.STDEditFieldLabel,'Visible','on');
                otherwise
                    set(app.Threshold2EditFieldLabel,'Visible','off');
                    set(app.Threshold2EditField,'Visible','off');
                    set(app.NEditField,'Visible','off');
                    set(app.NEditFieldLabel,'Visible','off');
                    set(app.STDEditField,'Visible','off');
                    set(app.STDEditFieldLabel,'Visible','off');
                    set(app.ThresholdEditField,'Visible','off');
                    set(app.ThresholdEditFieldLabel,'Visible','off');
                    set(app.IncludeDiagonalsCheckBox,'Visible','on');

            end
        
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
         
[filename, pathname] = uiputfile('*.ppm', 'Save Image As');

% Check if the user canceled the operation
if isequal(filename, 0) || isequal(pathname, 0)
    disp('Canceled');
else
    % Combine the path and filename
    fullFilePath = fullfile(pathname, filename);

    % Get the image data from the UIAxes
    imageObject = app.finalimage.Children;
    imageData = imageObject.CData;

    % Write the image to the chosen file
    imwrite(imageData, fullFilePath, 'ppm');

    disp(['Image saved as: ', fullFilePath]);

    % Close the figure associated with the UIAxes
    close(app.finalimage.Parent);

    % Open a new figure (replace 'FigureTitle' with an appropriate title)
    figure('Name', 'FigureTitle');
end


        end

        % Value changed function: BinarizationDropDown
        function BinarizationDropDownValueChanged(app, event)
            value=app.BinarizationDropDown.Value;
            switch value
                case 'Basic Global Thresholding'
                        set(app.ThereholdforBgEditField,'Visible','on');
                        set(app.ThereholdforBgEditFieldLabel,'Visible','on');
                 
                otherwise
                    set(app.ThereholdforBgEditField,'Visible','off');
                    set(app.ThereholdforBgEditFieldLabel,'Visible','off');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.saturationimage1 = uiaxes(app.UIFigure);
            app.saturationimage1.XTick = [];
            app.saturationimage1.YTick = [];
            app.intensityimage1 = uiaxes(app.UIFigure);
            app.intensityimage1.XTick = [];
            app.intensityimage1.YTick = [];
            app.Hueimage1 = uiaxes(app.UIFigure);
            app.Hueimage1.XTick = [];
            app.Hueimage1.YTick = [];
            app.finalimage = uiaxes(app.UIFigure);
            app.finalimage.XTick = [];
            app.finalimage.YTick = [];
            app.outputimage1 = uiaxes(app.UIFigure);
            app.outputimage1.XTick = [];
            app.outputimage1.YTick = [];
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.EdgeDetectionDropDownLabel = uilabel(app.UIFigure);
            app.EdgeDetectionDropDownLabel.Text = 'Edge Detection';
            app.EdgeDetectionDropDown = uidropdown(app.UIFigure);
            app.EdgeDetectionDropDown.Items = {'Choose', 'Prewitt', 'Sobel', 'Scharr', 'Kirsch', 'Marr Hildreth', 'Canny'};
            app.EdgeDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionDropDownValueChanged, true);
            app.EdgeDetectionDropDown.Value = 'Choose';
            app.BinarizationDropDownLabel = uilabel(app.UIFigure);
            app.BinarizationDropDownLabel.Text = 'Binarization';
            app.BinarizationDropDown = uidropdown(app.UIFigure);
            app.BinarizationDropDown.Items = {'Choose', 'Basic Global Thresholding', 'Optimal Global Threshold'};
            app.BinarizationDropDown.ValueChangedFcn = createCallbackFcn(app, @BinarizationDropDownValueChanged, true);
            app.BinarizationDropDown.Value = 'Choose';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TerminateButton = uibutton(app.UIFigure, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Text = 'Terminate';
            app.huelabel = uilabel(app.UIFigure);
            app.huelabel.Text = 'Hue';
            app.saturationlabel = uilabel(app.UIFigure);
            app.saturationlabel.Text = 'Saturation';
            app.intensitylabel = uilabel(app.UIFigure);
            app.intensitylabel.Text = 'Intensity';
            app.outputlabel = uilabel(app.UIFigure);
            app.outputlabel.Text = 'Mid-output Image';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.IncludeDiagonalsCheckBox = uicheckbox(app.UIFigure);
            app.IncludeDiagonalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeDiagonalsCheckBoxValueChanged, true);
            app.IncludeDiagonalsCheckBox.Text = 'Include Diagonal';
            app.ColorMapsDropDownLabel = uilabel(app.UIFigure);
            app.ColorMapsDropDownLabel.Text = 'ColorMaps';
            app.ColorMapsDropDown = uidropdown(app.UIFigure);
            app.ColorMapsDropDown.Items = {'NONE ', 'summer', 'cool', 'autumn', 'parula', 'hot'};
            app.ColorMapsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapsDropDownValueChanged, true);
            app.ColorMapsDropDown.Value = 'NONE ';
            app.finalimagelabel = uilabel(app.UIFigure);
            app.finalimagelabel.Text = 'Output Image';
            app.ADDColorButton = uibutton(app.UIFigure, 'push');
            app.ADDColorButton.ButtonPushedFcn = createCallbackFcn(app, @ADDColorButtonPushed, true);
            app.ADDColorButton.Text = 'ADD Color';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.STDEditFieldLabel = uilabel(app.UIFigure);
            app.STDEditFieldLabel.Text = 'STD';
            app.STDEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold   ';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.Threshold2EditFieldLabel = uilabel(app.UIFigure);
            app.Threshold2EditFieldLabel.Text = 'Threshold2 ';
            app.Threshold2EditField = uieditfield(app.UIFigure, 'text');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N      ';
            app.NEditField = uieditfield(app.UIFigure, 'text');
            app.ThereholdforBgEditFieldLabel = uilabel(app.UIFigure);
            app.ThereholdforBgEditFieldLabel.Text = 'Therehold(for Bg)';
            app.ThereholdforBgEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appp_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end